classdef huron_urdf_tester < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        Slider_6    matlab.ui.control.Slider
        Label_10    matlab.ui.control.Label
        Slider_5    matlab.ui.control.Slider
        Label_9     matlab.ui.control.Label
        Slider_4    matlab.ui.control.Slider
        Label_7     matlab.ui.control.Label
        Slider_3    matlab.ui.control.Slider
        Label_4     matlab.ui.control.Label
        Slider_2    matlab.ui.control.Slider
        Label_3     matlab.ui.control.Label
        Slider      matlab.ui.control.Slider
        Label       matlab.ui.control.Label
        RightPanel  matlab.ui.container.Panel
        Slider_12   matlab.ui.control.Slider
        Label_12    matlab.ui.control.Label
        Slider_11   matlab.ui.control.Slider
        Label_11    matlab.ui.control.Label
        Slider_10   matlab.ui.control.Slider
        Label_8     matlab.ui.control.Label
        Slider_9    matlab.ui.control.Slider
        Label_6     matlab.ui.control.Label
        Slider_8    matlab.ui.control.Slider
        Label_5     matlab.ui.control.Label
        Slider_7    matlab.ui.control.Slider
        Label_2     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        robot % robot from URDF
        conf % joint configuration
        ax % plot Axes object
    end
    
    methods (Access = private)
        
        function updatePlot(app, joint_i, val)
            app.conf(joint_i) = val;
            isColliding = checkCollision(app.robot, app.conf,'SkippedSelfCollisions','parent');
            if isColliding
                show(app.robot, app.conf, "PreservePlot", false, ...
                "FastUpdate", true, "Parent", app.ax, ...
                "Collisions", "on", "Visuals", "off")
            else
                show(app.robot, app.conf, "PreservePlot", false, ...
                "FastUpdate", true, "Parent", app.ax)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot = importrobot("urdf/huron_description_3.urdf", "DataFormat", "row");
            app.conf = zeros(1, 12);
            app.ax = show(app.robot);
            set(app.ax, "XLimMode", "manual", "YLimMode", "manual", "ZLimMode", "manual");
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(1, changingValue);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(2, changingValue);
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(3, changingValue);
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(4, changingValue);
        end

        % Value changing function: Slider_5
        function Slider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(5, changingValue);
        end

        % Value changing function: Slider_6
        function Slider_6ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(6, changingValue);
        end

        % Value changing function: Slider_7
        function Slider_7ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(7, changingValue);
        end

        % Value changing function: Slider_8
        function Slider_8ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(8, changingValue);
        end

        % Value changing function: Slider_9
        function Slider_9ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(9, changingValue);
        end

        % Value changing function: Slider_10
        function Slider_10ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(10, changingValue);
        end

        % Value changing function: Slider_11
        function Slider_11ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(11, changingValue);
        end

        % Value changing function: Slider_12
        function Slider_12ValueChanging(app, event)
            changingValue = event.Value;
            app.updatePlot(12, changingValue);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {322, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '1';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '2';
            app.Slider_2 = uislider(app.LeftPanel);
            app.Slider_2.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '3';
            app.Slider_3 = uislider(app.LeftPanel);
            app.Slider_3.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '4';
            app.Slider_4 = uislider(app.LeftPanel);
            app.Slider_4.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '5';
            app.Slider_5 = uislider(app.LeftPanel);
            app.Slider_5.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Slider_5ValueChanging, true);
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = '6';
            app.Slider_6 = uislider(app.LeftPanel);
            app.Slider_6.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Slider_6ValueChanging, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '7';
            app.Slider_7 = uislider(app.RightPanel);
            app.Slider_7.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_7.ValueChangingFcn = createCallbackFcn(app, @Slider_7ValueChanging, true);
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Text = '8';
            app.Slider_8 = uislider(app.RightPanel);
            app.Slider_8.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_8.ValueChangingFcn = createCallbackFcn(app, @Slider_8ValueChanging, true);
            app.Label_6 = uilabel(app.RightPanel);
            app.Label_6.Text = '9';
            app.Slider_9 = uislider(app.RightPanel);
            app.Slider_9.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_9.ValueChangingFcn = createCallbackFcn(app, @Slider_9ValueChanging, true);
            app.Label_8 = uilabel(app.RightPanel);
            app.Label_8.Text = '10';
            app.Slider_10 = uislider(app.RightPanel);
            app.Slider_10.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_10.ValueChangingFcn = createCallbackFcn(app, @Slider_10ValueChanging, true);
            app.Label_11 = uilabel(app.RightPanel);
            app.Label_11.Text = '11';
            app.Slider_11 = uislider(app.RightPanel);
            app.Slider_11.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_11.ValueChangingFcn = createCallbackFcn(app, @Slider_11ValueChanging, true);
            app.Label_12 = uilabel(app.RightPanel);
            app.Label_12.Text = '12';
            app.Slider_12 = uislider(app.RightPanel);
            app.Slider_12.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider_12.ValueChangingFcn = createCallbackFcn(app, @Slider_12ValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = huron_urdf_tester

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end